classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        IncarcaredategeospatialButton   matlab.ui.control.Button
        IncarcaredatecnstbtButton       matlab.ui.control.Button
        IncarcaredateworldometerButton  matlab.ui.control.Button
        ELmanButton                     matlab.ui.control.Button
        ListBoxLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        EpochEditFieldLabel             matlab.ui.control.Label
        EpochEditField                  matlab.ui.control.NumericEditField
        PredictEditFieldLabel           matlab.ui.control.Label
        PredictEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        epoch;
        predict;
        geo_timp_ro;
        geo_cazuri_totale_ro;
        geo_cazuri_active_ro;
        geo_decese_ro;
        geo_vindecati_ro;
        geo_nr_teste_ro;
        geo_cazuri_unice_ro;
        geo_rata_de_crestere_ro;
        geo_frecventa_pe_grupe_varste_ro;
        geo_frecventa_pe_grupe_frecventa_ro;
        geo_spatial_cazuri_sapgeo_timp_ro;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function untitled1_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to untitled1 (see VARARGIN)
            %
            %             % Choose default command line output for untitled1
            %             app.output = hObject;
            %
            %             % Update handles structure
            %             guidata(hObject, handles);
            
        end

        % Button pushed function: IncarcaredategeospatialButton
        function load_data2(app, event)
            % evalin('base', 'clear all')
            path='C:\Users\horva\Desktop\Practica\Practica_covid19_UTCN\Database\';
            
            % incarcare date pentru https://covid19.geo-spatial.org/
            Cazuri = xlsread( strcat(path,'Norbert\Cazuri.xlsx' )) ;
            
            app.geo_timp_ro=Cazuri(:,1);
            app.geo_cazuri_totale_ro=Cazuri(:,2);
            app.geo_cazuri_active_ro=Cazuri(:,3);
            app.geo_decese_ro=Cazuri(:,4);
            app.geo_vindecati_ro=Cazuri(:,5);
            %
            app.geo_nr_teste_ro=Cazuri(:,6);
            app.geo_cazuri_unice_ro=Cazuri(:,7);
            app.geo_rata_de_crestere_ro=Cazuri(:,8);
            app.geo_frecventa_pe_grupe_varste_ro=Cazuri(:,9);
            app.geo_frecventa_pe_grupe_frecventa_ro=Cazuri(:,10);
            % cazuri saptamanale
            x=Cazuri(:,18);
            app.geo_spatial_cazuri_sapgeo_timp_ro = x(~isnan(x));
            assignin('base','geo_timp_ro',app.geo_timp_ro)
            assignin('base','geo_cazuri_totale_ro',app.geo_cazuri_totale_ro)
            assignin('base','geo_cazuri_active_ro',app.geo_cazuri_active_ro)
            assignin('base','geo_decese_ro',app.geo_decese_ro)
            assignin('base','geo_vindecati_ro',app.geo_vindecati_ro)
            assignin('base','geo_nr_teste_ro',app.geo_nr_teste_ro)
            assignin('base','geo_cazuri_unice_ro',app.geo_cazuri_unice_ro)
            assignin('base','geo_rata_de_crestere_ro',app.geo_rata_de_crestere_ro)
            assignin('base','geo_frecventa_pe_grupe_varste_ro',app.geo_frecventa_pe_grupe_varste_ro)
            assignin('base','geo_frecventa_pe_grupe_frecventa_ro',app.geo_frecventa_pe_grupe_frecventa_ro)
            
            %             % salvare
            %             title = ('Salvare date geospatial in Workspace');
            %             labels = {'geo_timp_ro:','geo_cazuri_totale_ro:','geo_cazuri_active_ro:','geo_decese_ro:','geo_vindecati_ro:','geo_nr_teste_ro:','geo_cazuri_unice_ro:','geo_rata_de_crestere_ro:','geo_frecventa_pe_grupe_varste_ro:','geo_frecventa_pe_grupe_frecventa_ro:','geo_spatial_cazuri_sapgeo_timp_ro:'};
            %             vars = {'geo_timp_ro','geo_cazuri_totale_ro','geo_cazuri_active_ro','geo_decese_ro','geo_vindecati_ro','geo_nr_teste_ro','geo_cazuri_unice_ro','geo_rata_de_crestere_ro','geo_frecventa_pe_grupe_varste_ro','geo_frecventa_pe_grupe_frecventa_ro','geo_spatial_cazuri_sapgeo_timp_ro'};
            %             values = {geo_timp_ro,geo_cazuri_totale_ro,geo_cazuri_active_ro,geo_decese_ro,geo_vindecati_ro,geo_nr_teste_ro,geo_cazuri_unice_ro,geo_rata_de_crestere_ro,geo_frecventa_pe_grupe_varste_ro,geo_frecventa_pe_grupe_frecventa_ro,geo_spatial_cazuri_sapgeo_timp_ro};
            %             export2wsdlg(labels,vars,values);
            
        end

        % Button pushed function: IncarcaredatecnstbtButton
        function load_data_cnsbt(app, event)
            evalin('base', 'clear cns_cazuri_sapt')
            path='C:\Users\horva\Desktop\Practica\Practica_covid19_UTCN\Database\';
            cnstbt = xlsread( strcat(path,'Norbert\cnscbt.xlsx' )) ;
            cns_cazuri_sapt=cnstbt(:,1);
            %             % salvare
            %             title = ('Salvare date cnstbt in Workspace');
            %             labels = {'cns_cazuri_sapt:'};
            %             vars = {'cns_cazuri_sapt'};
            %             values = {cns_cazuri_sapt};
            %             export2wsdlg(labels,vars,values);
            assignin('base','cns_cazuri_sapt',cns_cazuri_sapt)
            
            
        end

        % Button pushed function: IncarcaredateworldometerButton
        function load_data_worldometer(app, event)
            
            path='C:\Users\horva\Desktop\Practica\Practica_covid19_UTCN\Database\';
            AU_HU_IS = xlsread( strcat(path,'Norbert\Australia_Ungaria_Israel_worldometer.xlsx' )) ;
            
            data_zile=AU_HU_IS(:,1);
            assignin('base','data_zile',data_zile)
            % Israel
            cazuri_totale_Israel_wo=AU_HU_IS(:,2);
            cazuri_noi_pe_zi_Israel_wo=AU_HU_IS(:,3);
            cazuri_active_Israel_wo=AU_HU_IS(:,4);
            decese_Israel_wo=AU_HU_IS(:,5);
            decese_pe_zi_Israel_wo=AU_HU_IS(:,6);
            assignin('base','cazuri_totale_Israel_wo',cazuri_totale_Israel_wo)
            assignin('base','cazuri_totale_Israel_wo',cazuri_noi_pe_zi_Israel_wo)
            assignin('base','cazuri_noi_pe_zi_Israel_wo',cazuri_active_Israel_wo)
            assignin('base','cazuri_active_Israel_wo',decese_Israel_wo)
            assignin('base','decese_pe_zi_Israel_wo',decese_pe_zi_Israel_wo)
            
            
            % Ungaria
            cazuri_totale_Ungaria_wo=AU_HU_IS(:,7);
            cazuri_noi_pe_zi_Ungaria_wo=AU_HU_IS(:,8);
            cazuri_active_Ungaria_wo=AU_HU_IS(:,9);
            decese_Ungaria_wo=AU_HU_IS(:,10);
            decese_pe_zi_Ungaria_wo=AU_HU_IS(:,11);
            assignin('base','cazuri_totale_Ungaria_wo',cazuri_totale_Ungaria_wo)
            assignin('base','cazuri_noi_pe_zi_Ungaria_wo',cazuri_noi_pe_zi_Ungaria_wo)
            assignin('base','cazuri_active_Ungaria_wo',cazuri_active_Ungaria_wo)
            assignin('base','decese_Ungaria_wo',decese_Ungaria_wo)
            assignin('base','decese_pe_zi_Ungaria_wo',decese_pe_zi_Ungaria_wo)
            
            % Austria
            cazuri_totale_Austria_wo=AU_HU_IS(:,12);
            cazuri_noi_pe_zi_Austria_wo=AU_HU_IS(:,13);
            cazuri_active_Austria_wo=AU_HU_IS(:,14);
            decese_Austria_wo=AU_HU_IS(:,15);
            decese_pe_zi_Austria_wo=AU_HU_IS(:,16);
            assignin('base','cazuri_totale_Austria_wo',cazuri_totale_Austria_wo)
            assignin('base','cazuri_noi_pe_zi_Austria_wo',cazuri_noi_pe_zi_Austria_wo)
            assignin('base','cazuri_active_Austria_wo',cazuri_active_Austria_wo)
            assignin('base','decese_Austria_wo',decese_Austria_wo)
            assignin('base','decese_pe_zi_Austria_wo',decese_pe_zi_Austria_wo)
            
            % Romania
            cazuri_totale_Romania_wo=AU_HU_IS(:,17);
            cazuri_noi_pe_zi_Romania_wo=AU_HU_IS(:,18);
            cazuri_active_Romania_wo=AU_HU_IS(:,19);
            decese_Romania_wo=AU_HU_IS(:,20);
            decese_pe_zi_Romania_wo=AU_HU_IS(:,21);
            assignin('base','cazuri_totale_Romania_wo',cazuri_totale_Romania_wo)
            assignin('base','cazuri_noi_pe_zi_Romania_wo',cazuri_noi_pe_zi_Romania_wo)
            assignin('base','cazuri_active_Romania_wo',cazuri_active_Romania_wo)
            assignin('base','decese_Romania_wo',decese_Romania_wo)
            assignin('base','decese_pe_zi_Romania_wo',decese_pe_zi_Romania_wo)


        end

        % Button pushed function: ELmanButton
        function func_elman(app, event)
            
           % function elman_neuron(timp,data,epoch,predict)
               % elman_neuron(geo_timp_ro,geo_cazuri_totale_ro,app.epoch,app.predict)
                clearvars  net net2
                nume_plot='as'%inputname(2)
                %Here is a series of Boolean inputs P, and another sequence T
                % which is 1 wherever P has had two 1's in a row.
                
                P=app.geo_timp_ro';
                T=app.geo_cazuri_totale_ro';
                
                if isempty(app.epoch)
                    app.epoch=4000;
                end
                if isempty(app.predict)
                    app.predict=800;
                end
                
                % We would like the network to recognize whenever two 1's
                % occur in a row.  First we arrange these values as sequences.
                
                Pseq = con2seq(P);
                Tseq = con2seq(T);
                
                % Here we create a network with one hidden layer of 10 neurons.
                
                net = newelm(P,T,17,{'tansig','purelin'});
                net.trainParam.goal=1e-320;
                net.trainparam.epochs = app.epoch;
                net.trainParam.max_fail = 200;
                
                
                % Then we train the network with a mean squared error goal of
                % 0.1, and simulate it.
                
                net2 = train(net,Pseq,Tseq);
                
                u=0:1: app.predict;
                uo= con2seq(u);
                y=sim(net2,uo) ;
                z = seq2con(y);
                op=cell2mat(z)
                figure;
                plot(u,op,'r')
                
                hold on
                plot(P,T,'b')
                legend(nume_plot,'Original', 'Interpreter', 'none')
                
                title(nume_plot, 'Interpreter', 'none');
            
            
        end

        % Value changed function: EpochEditField
        function EpochEditFieldValueChanged(app, event)
            value = app.EpochEditField.Value;
            app.epoch=value;
            
        end

        % Value changed function: PredictEditField
        function PredictEditFieldValueChanged(app, event)
            value = app.PredictEditField.Value;
            app.predict=value;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
                        rawdata1 = uigetfile('.mat','Select the MATLAB code file');   
            varlist = who('-file',rawdata1); 
            app.VariablesxaxisListBox.Items = varlist;
            app.VariablesyaxisListBox.Items = varlist;
            value = app.ListBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'untitled1';
            app.IncarcaredategeospatialButton = uibutton(app.figure1, 'push');
            app.IncarcaredategeospatialButton.ButtonPushedFcn = createCallbackFcn(app, @load_data2, true);
            app.IncarcaredategeospatialButton.Text = 'Incarcare date geo spatial';
            app.IncarcaredatecnstbtButton = uibutton(app.figure1, 'push');
            app.IncarcaredatecnstbtButton.ButtonPushedFcn = createCallbackFcn(app, @load_data_cnsbt, true);
            app.IncarcaredatecnstbtButton.Text = 'Incarcare date cnstbt';
            app.IncarcaredateworldometerButton = uibutton(app.figure1, 'push');
            app.IncarcaredateworldometerButton.ButtonPushedFcn = createCallbackFcn(app, @load_data_worldometer, true);
            app.IncarcaredateworldometerButton.Text = 'Incarcare date worldometer';
            app.ELmanButton = uibutton(app.figure1, 'push');
            app.ELmanButton.ButtonPushedFcn = createCallbackFcn(app, @func_elman, true);
            app.ELmanButton.Text = 'ELman';
            app.ListBoxLabel = uilabel(app.figure1);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.figure1);
            app.ListBox.Items = {'Item 1'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.EpochEditFieldLabel = uilabel(app.figure1);
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.figure1, 'numeric');
            app.EpochEditField.Limits = [200 Inf];
            app.EpochEditField.ValueChangedFcn = createCallbackFcn(app, @EpochEditFieldValueChanged, true);
            app.EpochEditField.Value = 200;
            app.PredictEditFieldLabel = uilabel(app.figure1);
            app.PredictEditFieldLabel.Text = 'Predict';
            app.PredictEditField = uieditfield(app.figure1, 'numeric');
            app.PredictEditField.Limits = [0 Inf];
            app.PredictEditField.ValueChangedFcn = createCallbackFcn(app, @PredictEditFieldValueChanged, true);
            app.PredictEditField.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @untitled1_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end