classdef untitled1_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        Button   matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function untitled1_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to untitled1 (see VARARGIN)
            %
            %             % Choose default command line output for untitled1
            %             app.output = hObject;
            %
            %             % Update handles structure
            %             guidata(hObject, handles);
        end

        % Button pushed function: Button
        function load_data(app, event)
            path='C:\Users\horva\Desktop\Practica\Practica_covid19_UTCN\Database\';
            
            % incarcare date pentru https://covid19.geo-spatial.org/
            Cazuri = xlsread( strcat(path,'Norbert\Cazuri.xlsx' )) ;
            
            geo_timp_ro=Cazuri(:,1);
            geo_cazuri_totale_ro=Cazuri(:,2);
            geo_cazuri_active_ro=Cazuri(:,3);
            geo_decese_ro=Cazuri(:,4);
            geo_vindecati_ro=Cazuri(:,5);
            
            labels = {'Save sum of A to variable named:' ...
                'Save mean of A to variable named:'};
            vars = {'geo_timp_ro','geo_cazuri_totale_ro','geo_cazuri_active_ro','geo_decese_ro','geo_vindecati_ro'};
            values = {geo_timp_ro,geo_cazuri_totale_ro,geo_cazuri_active_ro,geo_decese_ro,geo_vindecati_ro};
            export2wsdlg(labels,vars,values);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'untitled1';
            app.Button = uibutton(app.figure1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @load_data, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = untitled1_App_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @untitled1_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end